classdef poly_graph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GraphGenUIFigure           matlab.ui.Figure
        GraphLabel                 matlab.ui.control.Label
        RobotradiusEditField       matlab.ui.control.NumericEditField
        RobotradiusEditFieldLabel  matlab.ui.control.Label
        WorldsizeEditField         matlab.ui.control.NumericEditField
        WorldsizeEditFieldLabel    matlab.ui.control.Label
        GenLabel                   matlab.ui.control.Label
        VisibilityGraphButton      matlab.ui.control.Button
        AddendpointButton          matlab.ui.control.Button
        AddstartpointButton        matlab.ui.control.Button
        AddObstacleButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        values = []; % Description
        edges = [];
        start = [0 0];
        fin = [0 0];
        edge_count = 0;
        shape_count = 0;
        dist = []
        true_edges = []
        G = 0;
        flag = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddObstacleButton
        function AddObstacleButtonPushed(app, event)
            grid = app.WorldsizeEditField.Value;
            axis([1 grid 1 grid])
            roi = drawpolygon('Color','k', 'FaceAlpha', 0.5);
            app.shape_count = app.shape_count + 1;
            app.values = [app.values; roi.Position, (1:size(roi.Position, 1))' + app.edge_count, ones(size(roi.Position, 1), 1) * app.shape_count];
            for n = 1:size(roi.Position, 1)
                app.edge_count = app.edge_count + 1;
                if n == size(roi.Position, 1)
                    app.edges = [app.edges; roi.Position(end, :), roi.Position(1, :), app.edge_count app.edge_count-size(roi.Position, 1)+1];    
                else
                    app.edges = [app.edges; roi.Position(n, :), roi.Position(n + 1, :), app.edge_count app.edge_count + 1];    
                end
            end
            disp(app.values)
            disp(app.edges)
        end

        % Button pushed function: AddstartpointButton
        function AddstartpointButtonPushed(app, event)
            roi2 = drawpoint('Color','g');
            app.start = roi2.Position;
        end

        % Button pushed function: AddendpointButton
        function AddendpointButtonPushed(app, event)
            roi2 = drawpoint('Color','r');
            app.fin = roi2.Position;
        end

        % Button pushed function: VisibilityGraphButton
        function VisibilityGraphButtonPushed(app, event)
            grid = app.WorldsizeEditField.Value;
            app.values = [app.values; 1, 1, size(app.values, 1) + 1, app.shape_count + 1; 1, grid, size(app.values, 1) + 2, app.shape_count + 2; grid, grid, size(app.values, 1) + 3, app.shape_count + 3; grid, 1, size(app.values, 1) + 4, app.shape_count + 4; app.start, size(app.values, 1) + 5, app.shape_count + 5; app.fin, size(app.values, 1) + 6, app.shape_count + 6]; % Append start and end nodes
            %disp(app.values)
            for n = 1:size(app.values, 1)  % Por cada nodo
                for n2 = 1:size(app.values, 1)  % Por cada nodo
                    for m = 1:size(app.edges, 1)  % Por cada edge
                        if app.values(n, 4) ~= app.values(n2, 4)
                        avail = lineSegmentIntersect([app.values(n, 1:2), app.values(n2, 1:2)], [app.edges(m, 1:2), app.edges(m, 3:4)]);
                        %disp(isnan(avail.intMatrixX) || isinf(avail.intMatrixX) || avail.intMatrixX == 0)
                        if isnan(avail.intMatrixX) || isinf(avail.intMatrixX) || avail.intMatrixX == 0 % Si hay intersección
                            app.flag = 1;
                        else
                            app.flag = 0;
                            break
                        end
                        else
                            app.flag = 0;
                            break
                        end
                    end
                    if app.flag
                        app.true_edges = [app.true_edges; app.values(n, 3) app.values(n2, 3)];
                        %app.true_edges = setdiff(app.true_edges, app.edges(:, 3:4), 'rows');
                        app.dist = [app.dist; pdist([app.values(n, 1:2); app.values(n2, 1:2)])];
                    end
                end
            end
            
            E = table(app.true_edges(app.dist ~= 0, :), ones(size(app.dist(app.dist ~= 0), 1), 1), 1./app.dist(app.dist ~= 0), 'VariableNames', ["EndNodes", "Weight", "Eta"]);
             
            %disp(table(app.true_edges, app.dist, 1./app.dist, 'VariableNames', ["EndNodes", "Weight", "Eta"]))
            N = table(string((1:n)'), app.values(:, 1), app.values(:, 2), 'VariableNames', ["Name", "X", "Y"]);
            grafo = graph(E, N);
            grafo2 = simplify(grafo);
            hold on
            plot(grafo2, 'XData', app.values(:, 1), 'YData', app.values(:, 2), 'EdgeColor', [0.02,0.58,0.72],'NodeColor','k')
            obstacles = app.values;
            save('vis_graph.mat', 'grafo2', 'obstacles')
            % Si sale el error:
            % Error using save
            % Unable to write file vis_graph.mat: permission denied.
            % Solo cambiar al directorio
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GraphGenUIFigure = uifigure('Visible', 'off');
            app.GraphGenUIFigure.Name = 'GraphGen';
            app.AddObstacleButton = uibutton(app.GraphGenUIFigure, 'push');
            app.AddObstacleButton.ButtonPushedFcn = createCallbackFcn(app, @AddObstacleButtonPushed, true);
            app.AddObstacleButton.Text = 'Add Obstacle';
            app.AddstartpointButton = uibutton(app.GraphGenUIFigure, 'push');
            app.AddstartpointButton.ButtonPushedFcn = createCallbackFcn(app, @AddstartpointButtonPushed, true);
            app.AddstartpointButton.Text = 'Add start point';
            app.AddendpointButton = uibutton(app.GraphGenUIFigure, 'push');
            app.AddendpointButton.ButtonPushedFcn = createCallbackFcn(app, @AddendpointButtonPushed, true);
            app.AddendpointButton.Text = 'Add end point';
            app.VisibilityGraphButton = uibutton(app.GraphGenUIFigure, 'push');
            app.VisibilityGraphButton.ButtonPushedFcn = createCallbackFcn(app, @VisibilityGraphButtonPushed, true);
            app.VisibilityGraphButton.Text = 'Visibility Graph';
            app.GenLabel = uilabel(app.GraphGenUIFigure);
            app.GenLabel.Text = 'Gen';
            app.WorldsizeEditFieldLabel = uilabel(app.GraphGenUIFigure);
            app.WorldsizeEditFieldLabel.Text = 'World size';
            app.WorldsizeEditField = uieditfield(app.GraphGenUIFigure, 'numeric');
            app.WorldsizeEditField.Value = 10;
            app.RobotradiusEditFieldLabel = uilabel(app.GraphGenUIFigure);
            app.RobotradiusEditFieldLabel.Text = 'Robot radius';
            app.RobotradiusEditField = uieditfield(app.GraphGenUIFigure, 'numeric');
            app.GraphLabel = uilabel(app.GraphGenUIFigure);
            app.GraphLabel.Text = 'Graph';
            % Show the figure after all components are created
            app.GraphGenUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = poly_graph

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GraphGenUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GraphGenUIFigure)
        end
    end
end